classdef TRIAD_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRIADmethodUIFigure  matlab.ui.Figure
        StartButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        quaternionBodyInertialReal;% Description
        sunVectorInertial;
        sunVectorBody;
        magneticVectorInertial;
        magneticVectorBody;
        quaternionBodyInertialCalculated;
        rotationMatrixCalculated;
    end
 
    methods (Access = private)

        function [plot_U1,plot_U2,plot_U3] = initializeAxes(app)
            Axes_Body   = [1 0 0; 0 1 0; 0 0 1];
            plot_U1     = plot3(app.UIAxes,[0,Axes_Body(1,1)],[0,Axes_Body(1,2)],...
                [0,Axes_Body(1,3)],'-b','Linewidth',2,'Tag','plot_U1');
            plot_U2     = plot3(app.UIAxes,[0,Axes_Body(2,1)],[0,Axes_Body(2,2)],...
                [0,Axes_Body(2,3)],'-g','Linewidth',2,'Tag','plot_U2');
            plot_U3     = plot3(app.UIAxes,[0,Axes_Body(3,1)],[0,Axes_Body(3,2)],...
                [0,Axes_Body(3,3)],'-r','Linewidth',2,'Tag','plot_U3');
            plot3(app.UIAxes,[0,Axes_Body(1,1)],[0,Axes_Body(1,2)],...
                [0,Axes_Body(1,3)],'-k','Linewidth',2);
            plot3(app.UIAxes,[0,Axes_Body(2,1)],[0,Axes_Body(2,2)],...
                [0,Axes_Body(2,3)],'-k','Linewidth',2);
            plot3(app.UIAxes,[0,Axes_Body(3,1)],[0,Axes_Body(3,2)],...
                [0,Axes_Body(3,3)],'-k','Linewidth',2);
            legend(app.UIAxes, [plot_U1 plot_U2 plot_U3],'x_{body}','y_{body}','z_{body}','Location','southeast');
            x1 = 1.05; y1 = 0.05; z1 = 0.05;  txt1 = 'X_{I}'; text(app.UIAxes,x1,y1,z1,txt1,'FontSize',16,'FontWeight','bold')
            x1 = 0.05; y1 = 1.05; z1 = 0.05;  txt1 = 'Y_{I}'; text(app.UIAxes,x1,y1,z1,txt1,'FontSize',16,'FontWeight','bold')
            x1 = 0.05; y1 = 0.05; z1 = 1.05;  txt1 = 'Z_{I}'; text(app.UIAxes,x1,y1,z1,txt1,'FontSize',16,'FontWeight','bold')
%             title(app.UIAxes,'Calculated','Color',[1 1 1],'FontSize',18,'FontWeight','bold')
%             view(0,-90);
            Plots_Figure = figure('Name','Telemetry Calculated: Plots','NumberTitle','off',...
                'MenuBar','none','Color',[0.25 0.25 0.25],'Units','normalized',...
                'Position',[0.05,0.05,0.45,0.9], 'Tag', 'Plots_Figure');
            Plots_Figure.Units = 'pixels';
            % Euler angles plot
            Euler_Axes = subplot(2,1,1,'FontWeight','bold','YLim',[-180 180],...
                'Color',[0.55 0.55 0.55],'XColor',[1 1 1],'YColor',[1 1 1],...
                'XGrid','on','YGrid','on','GridColor',[1 1 1],'LineWidth',1.5,...
                'NextPlot','add','Tag','Euler_Axes');
            plot_E1_measured = plot(Euler_Axes,0,0,'-b','Linewidth',1,'Tag','plot_E1_measured');
            plot_E2_measured = plot(Euler_Axes,0,0,'-g','Linewidth',1,'Tag','plot_E2_measured');
            plot_E3_measured = plot(Euler_Axes,0,0,'-r','Linewidth',1,'Tag','plot_E3_measured');
            plot_E1_real = plot(Euler_Axes,0,0,'--b','Linewidth',1,'Tag','plot_E1_real');
            plot_E2_real = plot(Euler_Axes,0,0,'--g','Linewidth',1,'Tag','plot_E2_real');
            plot_E3_real = plot(Euler_Axes,0,0,'--r','Linewidth',1,'Tag','plot_E3_real');
            legend([plot_E1_measured plot_E1_real plot_E2_measured plot_E2_real ...
                plot_E3_measured plot_E3_real],'Roll_{calculated}','Roll_{real}',...
                'Pitch_{calculated}','Pitch_{real}','Yaw_{calculated}','Yaw_{real}',...
                'Location','bestoutside')
            title('Euler Angles vs. time','Color',[1 1 1],'FontSize',16,'FontWeight','bold')
            % Quaternion plot
            Quaternion_Axes = subplot(2,1,2,'FontWeight','bold','YLim',[-1 1],...
                'Color',[0.55 0.55 0.55],'XColor',[1 1 1],'YColor',[1 1 1],...
                'XGrid','on','YGrid','on','GridColor',[1 1 1],'LineWidth',1.5,...
                'NextPlot','add','Tag','Quaternion_Axes');
            plot_Q1_measured = plot(Quaternion_Axes,0,1,'-b','Linewidth',1,'Tag','plot_Q1_measured');
            plot_Q2_measured = plot(Quaternion_Axes,0,0,'-g','Linewidth',1,'Tag','plot_Q2_measured');
            plot_Q3_measured = plot(Quaternion_Axes,0,0,'-r','Linewidth',1,'Tag','plot_Q3_measured');
            plot_Q4_measured = plot(Quaternion_Axes,0,0,'-y','Linewidth',1,'Tag','plot_Q4_measured');
            legend([plot_Q1_measured plot_Q2_measured plot_Q3_measured plot_Q4_measured],...
                'Q_{1}^{calculated}','Q_{2}^{calculated}','Q_{3}^{calculated}','Q_{4}^{calculated}',...
                'Location','bestoutside')
            title('Quaternion vs. time','Color',[1 1 1],'FontSize',16,'FontWeight','bold')
        end
        
        function [qbn,qln,svn,svb,bvn,bvb]=read_data(app, client)
            data = readline(client);
        %     dateString = sscanf(data,"TIME %d-%d-%d:%d:%f");
        %     current_time = datetime(dateString(1),1,1,dateString(3),dateString(4),dateString(5))+dateString(2)-1;
        %     disp(current_time);
            data = readline(client);
            qbn=sscanf(data,"SC[0].AC.qbn = %e %e %e %e");
            data = readline(client);
            qln=sscanf(data,"SC[0].AC.qln = %e %e %e %e");
            data = readline(client);
            svn=sscanf(data,"SC[0].AC.svn = %e %e %e");
            svn=svn/norm(svn);
            data = readline(client);
            svb=sscanf(data,"SC[0].AC.svb = %e %e %e");
            svb=svb/norm(svb);
            data = readline(client);
            bvn=sscanf(data,"SC[0].AC.bvn = %e %e %e");
            bvn=bvn/norm(bvn);
            data = readline(client);
            bvb=sscanf(data,"SC[0].AC.bvb = %e %e %e");
            bvb=bvb/norm(bvb);
            data = readline(client);
            if (strcmp(data,"[EOF]"))
                readline(client);
                write(client,"Ack\n");
            end
        end
        
        function updatePlots(app, Euler_Angles_Real, Euler_Angles_Calculated,...
                Body2Inertial_Matrix, Quaternion, i)
            rad2deg         = 180/pi;
            PointsToPlot    = 40*5;

            Axes_Axes       = findobj(app.TRIADmethodUIFigure,'Tag','Axes_Axes');
            plot_U1         = findobj(Axes_Axes,'Tag','plot_U1');
            plot_U2         = findobj(Axes_Axes,'Tag','plot_U2');
            plot_U3         = findobj(Axes_Axes,'Tag','plot_U3');

            Plots_Figure = findobj(gcf, 'Tag', 'Plots_Figure');
            Euler_Axes      = findobj(Plots_Figure,'Tag','Euler_Axes');
            plot_E1_measured    = findobj(Euler_Axes,'Tag','plot_E1_measured');
            plot_E2_measured    = findobj(Euler_Axes,'Tag','plot_E2_measured');
            plot_E3_measured    = findobj(Euler_Axes,'Tag','plot_E3_measured');
            plot_E1_real        = findobj(Euler_Axes,'Tag','plot_E1_real');
            plot_E2_real        = findobj(Euler_Axes,'Tag','plot_E2_real');
            plot_E3_real        = findobj(Euler_Axes,'Tag','plot_E3_real');
            Quaternion_Axes = findobj(Plots_Figure,'Tag','Quaternion_Axes');
            plot_Q1_measured    = findobj(Quaternion_Axes,'Tag','plot_Q1_measured');
            plot_Q2_measured    = findobj(Quaternion_Axes,'Tag','plot_Q2_measured');
            plot_Q3_measured    = findobj(Quaternion_Axes,'Tag','plot_Q3_measured');
            plot_Q4_measured    = findobj(Quaternion_Axes,'Tag','plot_Q4_measured');

            items = size(plot_E1_measured.XData,2);
            if items<=PointsToPlot-1
                items = PointsToPlot;
                Euler_Axes.XLim         = [0 40];
                Quaternion_Axes.XLim    = [0 40];
            else
                Euler_Axes.XLim         = [plot_E1_measured.XData(items-PointsToPlot+1) plot_E1_measured.XData(end)+0.2];
                Quaternion_Axes.XLim    = [plot_Q1_measured.XData(items-PointsToPlot+1) plot_Q1_measured.XData(end)+0.2];
            end

            plot_U1.XData = [0,Body2Inertial_Matrix(1,1)];
            plot_U1.YData = [0,Body2Inertial_Matrix(2,1)];
            plot_U1.ZData = [0,Body2Inertial_Matrix(3,1)];
            plot_U2.XData = [0,Body2Inertial_Matrix(1,2)];
            plot_U2.YData = [0,Body2Inertial_Matrix(2,2)];
            plot_U2.ZData = [0,Body2Inertial_Matrix(3,2)];
            plot_U3.XData = [0,Body2Inertial_Matrix(1,3)];
            plot_U3.YData = [0,Body2Inertial_Matrix(2,3)];
            plot_U3.ZData = [0,Body2Inertial_Matrix(3,3)];

            plot_E1_measured.XData = [plot_E1_measured.XData(items-PointsToPlot+1:end) plot_E1_measured.XData(end)+0.2];
            plot_E1_measured.YData = [plot_E1_measured.YData(items-PointsToPlot+1:end) Euler_Angles_Calculated(i,3)*rad2deg];
            plot_E1_real.XData = [plot_E1_real.XData(items-PointsToPlot+1:end) plot_E1_real.XData(end)+0.2];
            plot_E1_real.YData = [plot_E1_real.YData(items-PointsToPlot+1:end) Euler_Angles_Real(i,3)*rad2deg];
            plot_E2_measured.XData = [plot_E2_measured.XData(items-PointsToPlot+1:end) plot_E2_measured.XData(end)+0.2];
            plot_E2_measured.YData = [plot_E2_measured.YData(items-PointsToPlot+1:end) Euler_Angles_Calculated(i,2)*rad2deg];
            plot_E2_real.XData = [plot_E2_real.XData(items-PointsToPlot+1:end) plot_E2_real.XData(end)+0.2];
            plot_E2_real.YData = [plot_E2_real.YData(items-PointsToPlot+1:end) Euler_Angles_Real(i,2)*rad2deg];
            plot_E3_measured.XData = [plot_E3_measured.XData(items-PointsToPlot+1:end) plot_E3_measured.XData(end)+0.2];
            plot_E3_measured.YData = [plot_E3_measured.YData(items-PointsToPlot+1:end) Euler_Angles_Calculated(i,1)*rad2deg];
            plot_E3_real.XData = [plot_E3_real.XData(items-PointsToPlot+1:end) plot_E3_real.XData(end)+0.2];
            plot_E3_real.YData = [plot_E3_real.YData(items-PointsToPlot+1:end) Euler_Angles_Real(i,1)*rad2deg];
            plot_Q1_measured.XData = [plot_Q1_measured.XData(items-PointsToPlot+1:end) plot_Q1_measured.XData(end)+0.2];
            plot_Q1_measured.YData = [plot_Q1_measured.YData(items-PointsToPlot+1:end) Quaternion(1)];
            plot_Q2_measured.XData = [plot_Q2_measured.XData(items-PointsToPlot+1:end) plot_Q2_measured.XData(end)+0.2];
            plot_Q2_measured.YData = [plot_Q2_measured.YData(items-PointsToPlot+1:end) Quaternion(2)];
            plot_Q3_measured.XData = [plot_Q3_measured.XData(items-PointsToPlot+1:end) plot_Q3_measured.XData(end)+0.2];
            plot_Q3_measured.YData = [plot_Q3_measured.YData(items-PointsToPlot+1:end) Quaternion(3)];
            plot_Q4_measured.XData = [plot_Q4_measured.XData(items-PointsToPlot+1:end) plot_Q4_measured.XData(end)+0.2];
            plot_Q4_measured.YData = [plot_Q4_measured.YData(items-PointsToPlot+1:end) Quaternion(4)];

            pause(0.05)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            initializeAxes(app);
            exitFlag =1;
            i = 0;
            client = tcpclient("localhost",10001);
            while (exitFlag)
                i = i+1;
                % Read operation
                [qbn,qln,svn,svb,bvn,bvb]=read_data(app, client);
                qbn_aux = qbn(4);
                qbn(2:4)=qbn(1:3);
                qbn(1)=qbn_aux;
                app.quaternionBodyInertialReal = [app.quaternionBodyInertialReal, qbn];
                app.sunVectorInertial = [app.sunVectorInertial, svn];
                app.sunVectorBody = [app.sunVectorBody, svb];
                app.magneticVectorInertial = [app.magneticVectorInertial, bvn];
                app.magneticVectorBody = [app.magneticVectorBody, bvb];
                Euler_Angles_Real(i,:)          = quat2eul(qbn');
                [Body2Inertial_Matrix,Quaternion]   = TRIAD_function([svb,bvb],[svn,bvn]);
                app.quaternionBodyInertialCalculated = [app.quaternionBodyInertialCalculated, Quaternion'];
                app.rotationMatrixCalculated(:,:,i) = Body2Inertial_Matrix;
                Euler_Angles_Calculated(i,:)    = rotm2eul(Body2Inertial_Matrix);
                updatePlots(app, Euler_Angles_Real, Euler_Angles_Calculated,...
                Body2Inertial_Matrix, Quaternion, i);
            end
        end

        % Close request function: TRIADmethodUIFigure
        function TRIADmethodUIFigureCloseRequest(app, event)
            Plots_Figure = findobj(gcf, 'Tag', 'Plots_Figure');
            delete(Plots_Figure);
            output.quaternionBodyInertialReal = app.quaternionBodyInertialReal';
            output.sunVectorInertial = app.sunVectorInertial';
            output.sunVectorBody = app.sunVectorBody';
            output.magneticVectorInertial = app.magneticVectorInertial';
            output.magneticVectorBody = app.magneticVectorBody';
            output.quaternionBodyInertialCalculated = app.quaternionBodyInertialCalculated';
            output.rotationMatrixCalculated = app.rotationMatrixCalculated;
            save('TRIAD_Output.mat',"output");
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRIADmethodUIFigure = uifigure('Visible', 'off');
            app.TRIADmethodUIFigure.Name = 'TRIAD method';
            app.TRIADmethodUIFigure.CloseRequestFcn = createCallbackFcn(app, @TRIADmethodUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.TRIADmethodUIFigure);
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [-1 1];
            app.UIAxes.Tag = 'Axes_Axes';
            app.StartButton = uibutton(app.TRIADmethodUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.TRIADmethodUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRIAD_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRIADmethodUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRIADmethodUIFigure)
        end
    end
end